classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ChoosetickrateLabel           matlab.ui.control.Label
        ChooseroadLabel               matlab.ui.control.Label
        roadSelection_ListBox         matlab.ui.control.ListBox
        Label2                        matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        selectTime_Slider             matlab.ui.control.Slider
        generateVisualization_Button  matlab.ui.control.Button
        date_DropDown                 matlab.ui.control.DropDown
        tickTime_DropDown             matlab.ui.control.DropDown
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.date_DropDown.Enable = false;
            app.tickTime_DropDown.Enable = false;
            app.selectTime_Slider.Enable = false;
            app.generateVisualization_Button.Enable = false;
            %{
            % Define the URL
            link = 'https://data.transportation.gov/resource/8ect-6jqj.json?location=';
            
            url = [link,app.roadSelection_ListBox.Value];
            
            % Specify the app token if needed
            appToken = 'VNEI5hNy8NgCnI0lmzVtmlCE1';
            options = weboptions('KeyName', 'app_token', 'KeyValue', appToken);
            
            % Retrieve data from the URL
            data = webread(url, options);
            
            % Extract unique locations
            locations = unique({data.location});
            
            % Create a MATLAB app and populate the dropdown
            app.roadSelection_ListBox.Items = locations;
            %}
        end

        % Value changed function: roadSelection_ListBox
        function roadSelection_ListBoxValueChanged2(app, event)
            value = app.roadSelection_ListBox.Value;
            app.date_DropDown.Enable = true;
            app.tickTime_DropDown.Enable = true;
            app.generateVisualization_Button.Enable = true;
          
            
            switch value
                case "us-101"
                    Items = ["7:50 - 8:05", "8:05 - 8:20", "8:20 - 8:35"];
                    ItemsData = {
                        [1118846979700, 1118847879700],
                        [1118847879700, 1118848779700],
                        [1118848779700, 1118849679700]
                    };
                    app.date_DropDown.Items = Items;
                    app.date_DropDown.ItemsData = ItemsData;
            
                case "i-80"
                    Items = ["16:00 - 16:15", "17:00 - 17:15", "17:15 - 17:30"];
                    ItemsData = {
                        [1113433135300, (1113433135300+900000)],
                        [(1113433135300+(4*900000)), (1113433135300+(5*900000))],
                        [(1113433135300+(5*900000)), (1113433135300+(6*900000))]
                    };
                    app.date_DropDown.Items = Items;
                    app.date_DropDown.ItemsData = ItemsData;
            
                case "lankershim"
                    Items = ["8:30 - 8:45", "8:45 - 9:00"];
                    ItemsData = {
                        [1118935680200, (1118935680200+900000)],
                        [(1118935680200+(2*900000)), (1118935680200+(3*900000))]
                    };
                    app.date_DropDown.Items = Items;
                    app.date_DropDown.ItemsData = ItemsData;
            
                case "peachtree"
                    Items = ["12:45 - 13:00", "16:00 - 16:15"];
                    ItemsData = {
                        [1163019100, 1163519100],
                        [1163519100, 1164063200]
                    };
                    app.date_DropDown.Items = Items;
                    app.date_DropDown.ItemsData = ItemsData;
            
                otherwise
                    app.date_DropDown.Enable = false;
                    app.tickTime_DropDown.Enable = false;
            end
            
        end

        % Button pushed function: generateVisualization_Button
        function generateVisualization_ButtonPushed(app, event)
            app.selectTime_Slider.Enable = true;
            tickString = app.tickTime_DropDown.Value;

            tickValue = str2double(tickString);
            disp(tickValue);
            app.selectTime_Slider.Limits = [900/tickValue, 900];
            app.selectTime_Slider.MinorTicks = 900/tickValue:900/tickValue:900;


        end

        % Value changed function: selectTime_Slider
        function selectTime_SliderValueChanged(app, event)
            value = app.selectTime_Slider.Value;
            disp(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.tickTime_DropDown = uidropdown(app.UIFigure);
            app.tickTime_DropDown.Items = {'15 minutes', '60 seconds', '30 seconds', '10 seconds'};
            app.tickTime_DropDown.ItemsData = {'1', '15', '30', '90'};
            app.tickTime_DropDown.Value = '15';
            app.date_DropDown = uidropdown(app.UIFigure);
            app.date_DropDown.Items = {};
            app.date_DropDown.Placeholder = 'choose time';
            app.date_DropDown.Value = {};
            app.generateVisualization_Button = uibutton(app.UIFigure, 'push');
            app.generateVisualization_Button.ButtonPushedFcn = createCallbackFcn(app, @generateVisualization_ButtonPushed, true);
            app.generateVisualization_Button.Text = 'Generate Visualization';
            app.selectTime_Slider = uislider(app.UIFigure);
            app.selectTime_Slider.ValueChangedFcn = createCallbackFcn(app, @selectTime_SliderValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.roadSelection_ListBox = uilistbox(app.UIFigure);
            app.roadSelection_ListBox.Items = {'i-80', 'us-101', 'peachtree', 'lankershim'};
            app.roadSelection_ListBox.ValueChangedFcn = createCallbackFcn(app, @roadSelection_ListBoxValueChanged2, true);
            app.roadSelection_ListBox.Value = {};
            app.ChooseroadLabel = uilabel(app.UIFigure);
            app.ChooseroadLabel.Text = 'Choose road:';
            app.ChoosetickrateLabel = uilabel(app.UIFigure);
            app.ChoosetickrateLabel.Text = 'Choose tick rate:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end